classdef LineScanAnnalyzerPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UnitsButtonGroup              matlab.ui.container.ButtonGroup
        PhysicalUnitButton            matlab.ui.control.ToggleButton
        PixelUnitButton               matlab.ui.control.ToggleButton
        ChunkToExamineEditField       matlab.ui.control.NumericEditField
        ChunkToExamineLabel           matlab.ui.control.Label
        RadonchunksizeEditField       matlab.ui.control.NumericEditField
        RadonchunksizeEditFieldLabel  matlab.ui.control.Label
        dtEditField                   matlab.ui.control.NumericEditField
        dtEditFieldLabel              matlab.ui.control.Label
        dxEditField                   matlab.ui.control.NumericEditField
        dxEditFieldLabel              matlab.ui.control.Label
        SampleButton                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SampleButton
        function sample_button(app, event)
            data = getimage(app.UIAxes);
            windowsize = app.RadonchunksizeEditField.Value;
            stepsize = 0.25*windowsize;
            k = app.ChunkToExamineEditField.Value;
            data_chunk=data(:,1+(k-1)*stepsize:(k-1)*stepsize+windowsize);
            plot_diagnostic(data_chunk,windowsize);
            disp(['plotting sample from ' num2str(1+(k-1)*stepsize)])
        end

        % Callback function
        function start_plot(app, event)
          set(app.ActiveButton,'Value',true);
        end

        % Callback function
        function stop_plot(app, event)
          set(app.ActiveButton,'Value',false);
        end

        % Callback function
        function end_plot(app, event)
            quit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Line Scan Image')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Pixels')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Result')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Slope')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Flux')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Cell Count')
            zlabel(app.UIAxes2, 'Z')
            app.SampleButton = uibutton(app.UIFigure, 'push');
            app.SampleButton.ButtonPushedFcn = createCallbackFcn(app, @sample_button, true);
            app.SampleButton.Text = 'Sample';
            app.dxEditFieldLabel = uilabel(app.UIFigure);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.UIFigure, 'numeric');
            app.dxEditField.Value = 0.1606;
            app.dtEditFieldLabel = uilabel(app.UIFigure);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEditField = uieditfield(app.UIFigure, 'numeric');
            app.dtEditField.Value = 0.24;
            app.RadonchunksizeEditFieldLabel = uilabel(app.UIFigure);
            app.RadonchunksizeEditFieldLabel.Text = {'Radon '; 'chunk size'};
            app.RadonchunksizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadonchunksizeEditField.Value = 100;
            app.ChunkToExamineLabel = uilabel(app.UIFigure);
            app.ChunkToExamineLabel.Text = {'Chunk To '; 'Examine'};
            app.ChunkToExamineEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChunkToExamineEditField.Value = 1;
            app.UnitsButtonGroup = uibuttongroup(app.UIFigure);
            app.UnitsButtonGroup.Title = 'Units';
            app.PixelUnitButton = uitogglebutton(app.UnitsButtonGroup);
            app.PixelUnitButton.Text = 'Pixel Unit';
            app.PixelUnitButton.Value = true;
            app.PhysicalUnitButton = uitogglebutton(app.UnitsButtonGroup);
            app.PhysicalUnitButton.Text = 'Physical Unit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LineScanAnnalyzerPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end