classdef RadonParameterSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        maxareaEditField         matlab.ui.control.EditField
        maxareaEditFieldLabel    matlab.ui.control.Label
        minareaEditField         matlab.ui.control.EditField
        minareaEditFieldLabel    matlab.ui.control.Label
        SelectFileButton         matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        SelectFolderButton       matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        UpdateButton             matlab.ui.control.Button
        ThresholdEditField       matlab.ui.control.EditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        SigmaEditField           matlab.ui.control.EditField
        SigmaEditFieldLabel      matlab.ui.control.Label
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        file_path
        file_name
        image
        result
        chunk
        fig
    end
    
    methods (Access = public)
        
        function plot_result(app,start_time,end_time)
            down_sampling_factor = 3;
            chunk_offset = 15000;
            chunk_length = 1000;
            stimulationi = 1;
            start_timei = start_time(stimulationi);
            end_timei = end_time(stimulationi);
            [imagers,all_slopes,all_locations] = RadonBackPlotter.get_plotting_information(app.image,...
               start_timei,end_timei,chunk_offset,chunk_length,app.result,down_sampling_factor);
            RadonBackPlotter.plot_strips(imagers,all_locations,all_slopes,start_timei,...
               end_timei,chunk_offset,chunk_length,down_sampling_factor,1)
            app.chunk = app.image(:,start_timei-chunk_offset:start_timei-chunk_offset+1000);
            app.SigmaEditField.Value = '5';
            app.ThresholdEditField.Value = '3';
            app.minareaEditField.Value = '50';
            app.maxareaEditField.Value = '2000';
            app.plot_daignostics()
        end

        function plot_daignostics(app)
            varargin = {app.SigmaEditField.Value,app.ThresholdEditField.Value,app.minareaEditField.Value,app.maxareaEditField.Value};
            varargin = cellfun(@str2num,varargin);
            varargin = {varargin(1),varargin(2),varargin(3),varargin(4)};
            [local_max_theta,local_max_r,~,R,local_max_index,refined_mask] = roi_radon(app.chunk,1:180,varargin{:});
            local_max_r_and_theta = [local_max_r',local_max_theta'];
            R(~refined_mask) = R(~refined_mask)*0.5;
            RadonTools.visualize_result(app.UIAxes,app.UIAxes_2,app.chunk,R,local_max_index',local_max_r_and_theta)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.file_path = uigetdir('/net/dk-server/bholloway/Zhongkai');
            app.ListBox.Items = FileHandler.find_experiment_names_in_analysis_folder(app.file_path);
%             app.fig = figure;
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            image_path = fullfile(app.file_path,append(app.ListBox.Value,'.tif'));
            mat_path = fullfile(app.file_path,append(app.ListBox.Value,'.mat'));
            app.image = FileHandler.load_image_data(image_path);
            mat = load(mat_path, 'start_time','end_time','result');
            app.result = mat.result;
            app.plot_result(mat.start_time,mat.end_time)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.plot_daignostics()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'text');
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'text');
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ListBox = uilistbox(app.UIFigure);
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.minareaEditFieldLabel = uilabel(app.UIFigure);
            app.minareaEditFieldLabel.Text = 'min area';
            app.minareaEditField = uieditfield(app.UIFigure, 'text');
            app.maxareaEditFieldLabel = uilabel(app.UIFigure);
            app.maxareaEditFieldLabel.Text = 'max area';
            app.maxareaEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadonParameterSelector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end