classdef linescan_qc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        QCfinishedEditField       matlab.ui.control.NumericEditField
        QCfinishedEditFieldLabel  matlab.ui.control.Label
        FilesEditField            matlab.ui.control.NumericEditField
        FilesEditFieldLabel       matlab.ui.control.Label
        PathEditField             matlab.ui.control.EditField
        PathEditFieldLabel        matlab.ui.control.Label
        SetDirectoryButton        matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        directory
        files
    end
    
    methods (Access = public)
        
        function set_path(app,path)
            app.directory = path;
            app.PathEditField.Value = path;
            app.files = dir(fullfile(app.directory,'*.mat'));
            app.FilesEditField.Value = numel(app.files);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetDirectoryButton
        function SetDirectoryButtonPushed(app, event)
            path = uigetdir;
            app.set_path(path);
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            app.set_path(app.PathEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            app.SetDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDirectoryButtonPushed, true);
            app.SetDirectoryButton.Text = 'Set Directory';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.FilesEditFieldLabel = uilabel(app.UIFigure);
            app.FilesEditFieldLabel.Text = 'Files';
            app.FilesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilesEditField.Editable = 'off';
            app.QCfinishedEditFieldLabel = uilabel(app.UIFigure);
            app.QCfinishedEditFieldLabel.Text = 'QC finished';
            app.QCfinishedEditField = uieditfield(app.UIFigure, 'numeric');
            app.QCfinishedEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linescan_qc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end