classdef PowerboxModPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FrameEndEditField             matlab.ui.control.NumericEditField
        FrameEndEditFieldLabel        matlab.ui.control.Label
        FrameStartEditField           matlab.ui.control.NumericEditField
        FrameStartEditFieldLabel      matlab.ui.control.Label
        Po2PowerEditField             matlab.ui.control.EditField
        Po2PowerEditFieldLabel        matlab.ui.control.Label
        VesselPowerEditField          matlab.ui.control.EditField
        VesselPowerEditFieldLabel     matlab.ui.control.Label
        LoadPowerBoxSettingsButton    matlab.ui.control.Button
        SavePowerBoxSettingsButton    matlab.ui.control.Button
        StartyEditField_2             matlab.ui.control.EditField
        StartyEditField_3Label        matlab.ui.control.Label
        EndyEditField_2               matlab.ui.control.EditField
        EndyEditFieldLabel_2          matlab.ui.control.Label
        EndxEditField_2               matlab.ui.control.EditField
        EndxLabel                     matlab.ui.control.Label
        StartxEditField_2             matlab.ui.control.EditField
        StartxLabel_2                 matlab.ui.control.Label
        EndyEditField                 matlab.ui.control.EditField
        EndyEditFieldLabel            matlab.ui.control.Label
        EndxEditField                 matlab.ui.control.EditField
        EndxEditFieldLabel            matlab.ui.control.Label
        StartyEditField               matlab.ui.control.EditField
        StartyLabel                   matlab.ui.control.Label
        StartxEditField               matlab.ui.control.EditField
        StartxLabel                   matlab.ui.control.Label
        ChannelEditField              matlab.ui.control.EditField
        ChannelEditFieldLabel         matlab.ui.control.Label
        numberoflinestoskipEditField  matlab.ui.control.EditField
        numberoflinestoskipLabel      matlab.ui.control.Label
        SelectPO2RegionButton         matlab.ui.control.Button
        SelectVesselRegionButton      matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        hSI
        image
        nx
        ny
        mask
        fused_image
    end
    
    methods (Access = private)
        function update_plot(app)
            app.fused_image = imfuse(app.image,app.mask,'falsecolor','Scaling','independent','ColorChannels',[1 2 2]);
            imagesc(app.UIAxes,app.fused_image)
        end
        function update_po2_postion(app,startx,starty,endx,endy)
            app.StartxEditField_2.Value = num2str(startx);
            app.StartyEditField_2.Value = num2str(starty);
            app.EndxEditField_2.Value = num2str(endx);
            app.EndyEditField_2.Value = num2str(endy);
        end
        function update_vessel_postion(app,startx,starty,endx,endy)
            app.StartxEditField.Value = num2str(startx);
            app.StartyEditField.Value = num2str(starty);
            app.EndxEditField.Value = num2str(endx);
            app.EndyEditField.Value = num2str(endy);
        end
        function [startx,starty,endx,endy] = get_vessel_coordinate_from_edit_field(app)
            startx = str2double(app.StartxEditField.Value);
            starty = str2double(app.StartyEditField.Value);
            endx = str2double(app.EndxEditField.Value);
            endy = str2double(app.EndyEditField.Value);
        end
        function [startx,starty,endx,endy] = get_po2_coordinate_from_edit_field(app)
            startx = str2double(app.StartxEditField_2.Value);
            starty = str2double(app.StartyEditField_2.Value);
            endx = str2double(app.EndxEditField_2.Value);
            endy = str2double(app.EndyEditField_2.Value);
        end
        function update_vessel_box_from_edit_field(app)
            [startx,starty,endx,endy] = app.get_vessel_coordinate_from_edit_field();
            app.update_vessel_box(startx,starty,endx,endy)
        end
        function update_po2_box_from_edit_field(app)
            [startx,starty,endx,endy] = app.get_po2_coordinate_from_edit_field();
            app.update_po2_box(startx,starty,endx,endy)
        end
        function power = parse_power_string(app,power_string)
            power = strsplit(power_string,' ');
            for i = 1:numel(power)
                power{i} = str2double(power{i});
            end
            power = cell2mat(power);
        end
        function box = create_power_box_structure(app,rect,power_string)
            box.rect = rect;
            box.powers = app.parse_power_string(power_string);
            box.name = [];
            box.oddLines = 1;
            box.evenLines = 1;
            box.mask = [];
        end
        function update_vessel_box(app,startx,starty,endx,endy)
            rect = [startx/app.nx,starty/app.ny,(endx-startx)/app.nx,(endy-starty)/app.ny];
            power_string = app.VesselPowerEditField.Value;
            box = app.create_power_box_structure(rect,power_string);
            app.hSI.hBeams.powerBoxes(1,:) = box;
            app.mask(app.mask==1)=0;
            app.mask(starty:endy,startx:endx)=0;
            app.mask = add_rectangle_to_mask(rect,app.mask,1);
            app.update_plot()
        end
        
        function update_po2_box(app,startx,starty,endx,endy)
            n_line_to_skip = str2num(app.numberoflinestoskipEditField.Value);
            lines = starty:n_line_to_skip+1:endy;
            app.mask(app.mask>1)=0;
            app.mask(starty:endy,startx:endx)=0;
            app.hSI.hBeams.powerBoxes(2:end) = [];
            for i =1:numel(lines)
                line = lines(i);
                rect = [startx/app.nx,line/app.ny,(endx-startx)/app.nx,0];
                app.mask = add_rectangle_to_mask(rect,app.mask,2);
                power_string = app.Po2PowerEditField.Value;
                box = app.create_power_box_structure(rect,power_string);
                app.hSI.hBeams.powerBoxes(i+1) = box;
            end
            app.update_plot()
        end
        function [startx,starty,endx,endy] = prompt_region_selection(app)
            fig = figure;
            imagesc(app.fused_image); 
            [startx,starty] = getpts(fig);
            [endx,endy] = getpts(fig);
            close
            coords = num2cell(floor([startx,starty,endx,endy]));
            [startx,starty,endx,endy] = deal(coords{:});
        end
        
        function initialize_image(app)
             channel = str2num(app.ChannelEditField.Value);
             app.image = app.hSI.hDisplay.lastAveragedFrame{channel};
             app.fused_image = app.image;
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.hSI = evalin('base', 'hSI');
           app.initialize_image();
           [app.ny,app.nx] = size(app.image);
           imagesc(app.UIAxes,app.image)
           xlim(app.UIAxes,[0 app.nx])
           ylim(app.UIAxes,[0 app.ny])
           app.mask = zeros(app.ny,app.nx);
           app.hSI.hBeams.enablePowerBox = 1;
           app.FrameStartEditField.Value = app.hSI.hBeams.powerBoxStartFrame;
           app.FrameEndEditField.Value = app.hSI.hBeams.powerBoxEndFrame;
            app.hSI.hBeams.powerBoxes(:) = [];
        end

        % Button pushed function: SelectVesselRegionButton
        function select_vessel_region(app, event)
            [startx,starty,endx,endy] = app.prompt_region_selection();
            app.update_vessel_postion(startx,starty,endx,endy);
            app.update_vessel_box(startx,starty,endx,endy)
        end

        % Button pushed function: SelectPO2RegionButton
        function select_po2_region(app, event)
            [startx,starty,endx,endy] = app.prompt_region_selection();
            app.update_po2_postion(startx,starty,endx,endy);
            app.update_po2_box(startx,starty,endx,endy)
        end

        % Value changed function: StartxEditField
        function StartxEditFieldValueChanged(app, event)
            app.update_vessel_box_from_edit_field();
        end

        % Value changed function: EndxEditField
        function EndxEditFieldValueChanged(app, event)
            app.update_vessel_box_from_edit_field();
        end

        % Value changed function: StartyEditField
        function StartyEditFieldValueChanged(app, event)
            app.update_vessel_box_from_edit_field();
        end

        % Value changed function: EndyEditField
        function EndyEditFieldValueChanged(app, event)
            app.update_vessel_box_from_edit_field();
        end

        % Value changed function: StartxEditField_2
        function StartxEditField_2ValueChanged(app, event)
            app.update_po2_box_from_edit_field();
        end

        % Value changed function: EndxEditField_2
        function EndxEditField_2ValueChanged(app, event)
            app.update_po2_box_from_edit_field();
        end

        % Value changed function: StartyEditField_2
        function StartyEditField_2ValueChanged(app, event)
            app.update_po2_box_from_edit_field();
        end

        % Value changed function: EndyEditField_2
        function EndyEditField_2ValueChanged(app, event)
            app.update_po2_box_from_edit_field();
        end

        % Button pushed function: SavePowerBoxSettingsButton
        function SavePowerBoxSettingsButtonPushed(app, event)
            [file,path] = uiputfile('*.mat','power_box_setting');
            figure(app.UIFigure);
            snx = app.nx;
            sny = app.ny;
            smask = app.mask;
            [startx,starty,endx,endy] = app.get_vessel_coordinate_from_edit_field();
            coord_vessel  = num2cell([startx,starty,endx,endy]);
            [startx,starty,endx,endy] = app.get_po2_coordinate_from_edit_field();
            po2_coord  = num2cell([startx,starty,endx,endy]);
            po2_power_string = app.Po2PowerEditField.Value;
            vessel_power_string = app.VesselPowerEditField.Value;
            frame_start = app.FrameStartEditField.Value;
            frame_end = app.FrameEndEditField.Value;
            res_scanner = get_res_scanner(app.hSI);
            save(strcat(path,file),'snx','sny','smask','coord_vessel','po2_coord','po2_power_string','vessel_power_string','frame_start','frame_end','res_scanner')
            figure(app.UIFigure);
        end

        % Button pushed function: LoadPowerBoxSettingsButton
        function LoadPowerBoxSettingsButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            figure(app.UIFigure);
            load(strcat(path,file),'snx','sny','smask','coord_vessel','po2_coord','po2_power_string','vessel_power_string','frame_start','frame_end');
            assert(snx==app.nx,'imaging field dimensions do not match');
            assert(sny==app.ny,'imaging field dimensions do not match');
            app.Po2PowerEditField.Value = po2_power_string;
            app.VesselPowerEditField.Value = vessel_power_string;
            app.FrameStartEditField.Value = frame_start;
            app.FrameEndEditField.Value = frame_end;
            [startx,starty,endx,endy] = deal(coord_vessel{:});
            app.update_vessel_postion(startx,starty,endx,endy);
            app.update_vessel_box(startx,starty,endx,endy);
            [startx,starty,endx,endy] = deal(po2_coord{:});
            app.update_po2_postion(startx,starty,endx,endy);
            app.update_po2_box(startx,starty,endx,endy);
            app.mask = smask;
            app.update_plot();
        end

        % Value changed function: ChannelEditField
        function ChannelEditFieldValueChanged(app, event)
            app.initialize_image();
            app.update_plot()
        end

        % Value changed function: FrameStartEditField
        function FrameStartEditFieldValueChanged(app, event)
            app.hSI.hBeams.powerBoxStartFrame = app.FrameStartEditField.Value;
        end

        % Value changed function: FrameEndEditField
        function FrameEndEditFieldValueChanged(app, event)
            app.hSI.hBeams.powerBoxEndFrame  = app.FrameEndEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Average Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectVesselRegionButton = uibutton(app.UIFigure, 'push');
            app.SelectVesselRegionButton.ButtonPushedFcn = createCallbackFcn(app, @select_vessel_region, true);
            app.SelectVesselRegionButton.Text = 'Select Vessel Region';
            app.SelectPO2RegionButton = uibutton(app.UIFigure, 'push');
            app.SelectPO2RegionButton.ButtonPushedFcn = createCallbackFcn(app, @select_po2_region, true);
            app.SelectPO2RegionButton.Text = 'Select PO2 Region';
            app.numberoflinestoskipLabel = uilabel(app.UIFigure);
            app.numberoflinestoskipLabel.Text = {'number of'; ' lines to skip'};
            app.numberoflinestoskipEditField = uieditfield(app.UIFigure, 'text');
            app.numberoflinestoskipEditField.Value = '1';
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelEditFieldValueChanged, true);
            app.ChannelEditField.Value = '3';
            app.StartxLabel = uilabel(app.UIFigure);
            app.StartxLabel.Text = 'Start x';
            app.StartxEditField = uieditfield(app.UIFigure, 'text');
            app.StartxEditField.ValueChangedFcn = createCallbackFcn(app, @StartxEditFieldValueChanged, true);
            app.StartyLabel = uilabel(app.UIFigure);
            app.StartyLabel.Text = 'Start y';
            app.StartyEditField = uieditfield(app.UIFigure, 'text');
            app.StartyEditField.ValueChangedFcn = createCallbackFcn(app, @StartyEditFieldValueChanged, true);
            app.EndxEditFieldLabel = uilabel(app.UIFigure);
            app.EndxEditFieldLabel.Text = 'End x';
            app.EndxEditField = uieditfield(app.UIFigure, 'text');
            app.EndxEditField.ValueChangedFcn = createCallbackFcn(app, @EndxEditFieldValueChanged, true);
            app.EndyEditFieldLabel = uilabel(app.UIFigure);
            app.EndyEditFieldLabel.Text = 'End y';
            app.EndyEditField = uieditfield(app.UIFigure, 'text');
            app.EndyEditField.ValueChangedFcn = createCallbackFcn(app, @EndyEditFieldValueChanged, true);
            app.StartxLabel_2 = uilabel(app.UIFigure);
            app.StartxLabel_2.Text = 'Start x';
            app.StartxEditField_2 = uieditfield(app.UIFigure, 'text');
            app.StartxEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartxEditField_2ValueChanged, true);
            app.EndxLabel = uilabel(app.UIFigure);
            app.EndxLabel.Text = 'End x';
            app.EndxEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EndxEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndxEditField_2ValueChanged, true);
            app.EndyEditFieldLabel_2 = uilabel(app.UIFigure);
            app.EndyEditFieldLabel_2.Text = 'End y';
            app.EndyEditField_2 = uieditfield(app.UIFigure, 'text');
            app.EndyEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndyEditField_2ValueChanged, true);
            app.StartyEditField_3Label = uilabel(app.UIFigure);
            app.StartyEditField_3Label.Text = 'Start y';
            app.StartyEditField_2 = uieditfield(app.UIFigure, 'text');
            app.StartyEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartyEditField_2ValueChanged, true);
            app.SavePowerBoxSettingsButton = uibutton(app.UIFigure, 'push');
            app.SavePowerBoxSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePowerBoxSettingsButtonPushed, true);
            app.SavePowerBoxSettingsButton.Text = 'Save Power Box Settings';
            app.LoadPowerBoxSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadPowerBoxSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPowerBoxSettingsButtonPushed, true);
            app.LoadPowerBoxSettingsButton.Text = 'Load Power Box Settings';
            app.VesselPowerEditFieldLabel = uilabel(app.UIFigure);
            app.VesselPowerEditFieldLabel.Text = 'Vessel Power';
            app.VesselPowerEditField = uieditfield(app.UIFigure, 'text');
            app.VesselPowerEditField.Value = 'NaN NaN';
            app.Po2PowerEditFieldLabel = uilabel(app.UIFigure);
            app.Po2PowerEditFieldLabel.Text = 'Po2 Power';
            app.Po2PowerEditField = uieditfield(app.UIFigure, 'text');
            app.Po2PowerEditField.Value = 'NaN NaN';
            app.FrameStartEditFieldLabel = uilabel(app.UIFigure);
            app.FrameStartEditFieldLabel.Text = 'Frame Start';
            app.FrameStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameStartEditField.ValueChangedFcn = createCallbackFcn(app, @FrameStartEditFieldValueChanged, true);
            app.FrameStartEditField.Value = 1;
            app.FrameEndEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEndEditFieldLabel.Text = 'Frame End';
            app.FrameEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEndEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEndEditFieldValueChanged, true);
            app.FrameEndEditField.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PowerboxModPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end